classdef TX_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image2                   matlab.ui.control.Image
        TerahertzCommunicationTestTransmiterLabel  matlab.ui.control.Label
        BaudrateEditField        matlab.ui.control.EditField
        BaudrateEditFieldLabel   matlab.ui.control.Label
        PortEditField            matlab.ui.control.EditField
        PortEditFieldLabel       matlab.ui.control.Label
        ConnectPortButton        matlab.ui.control.Button
        StatusTextArea           matlab.ui.control.TextArea
        StatusTextAreaLabel      matlab.ui.control.Label
        SendDataButton           matlab.ui.control.Button
        LoadImageButton          matlab.ui.control.Button
        PathTextArea             matlab.ui.control.TextArea
        PathTextAreaLabel        matlab.ui.control.Label
        ImportImageButton        matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        BaudrateDropDown         matlab.ui.control.DropDown
        BaudrateDropDownLabel    matlab.ui.control.Label
        SelectPortDropDown       matlab.ui.control.DropDown
        SelectPortDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        ComDataWrite    % Occupied when serial port open
        ImageRead       % RGB jpg image read from file
        ImageSend       % Serialized data prepared to send
        ImagePath       % Image path
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectPortDropDown
        function SelectPortDropDownValueChanged(app, event)
            value = app.SelectPortDropDown.Value;
            app.StatusTextArea.Value = sprintf("%s %s successful!", "Set port", value);
        end

        % Drop down opening function: SelectPortDropDown
        function SelectPortDropDownOpening(app, event)
            app.SelectPortDropDown.Items = serialportlist;
        end

        % Button pushed function: ConnectPortButton
        function ConnectPorButtonPushed(app, event)
            port = app.SelectPortDropDown.Value;
            baudrate = str2double(app.BaudrateDropDown.Value);
            % If the port is occupied, close it, then open it in MATLAB
            scom = instrfind;
            if scom ~= 0
                fclose(scom);
                delete(scom);
            else
                scom = serialport(port,baudrate);
                app.ComDataWrite = scom;
            end
            app.StatusTextArea.Value = sprintf("%s %s successful!", "Connect port", port);
            app.PortEditField.Value = sprintf("%s", port);
            app.BaudrateEditField.Value = sprintf("%d", baudrate);
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            [filename, filepath]=uigetfile({'*.jpg'},'Please select a picture');
            app.ImageRead = imread([filepath, filename]);
            if isequal(filename,0) || isequal(filepath,0)
                errordlg('No image chosen, please select again','Error');
                return;
            else
                app.ImagePath = [filepath, filename];
                app.PathTextArea.Value = app.ImagePath;
                app.StatusTextArea.Value = sprintf("Import image successful!");
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            image_rgb = app.ImageRead;
            % Sending preparation
            image_grey = rgb2gray(image_rgb);                       % Transform into grey image
            [image_h, image_w] = size(image_grey);                  % Record the height and width of image
            image_data = reshape(image_grey, 1, image_h.*image_w);  % Serialize the data
            % Start with 0, COM sends h and w after 0, then sends DATA...
            app.ImageSend = [uint8(27), uint8(image_h), uint8(image_w), image_data];
            % Show the image locally
            app.Image.ImageSource = app.ImagePath;
            app.StatusTextArea.Value = sprintf("Image ready to send!");
        end

        % Value changed function: BaudrateDropDown
        function BaudrateDropDownValueChanged(app, event)
            value = app.BaudrateDropDown.Value;
            app.StatusTextArea.Value = sprintf("%s %s successful!", "Set baudrate", value);
        end

        % Button pushed function: SendDataButton
        function SendDataButtonPushed(app, event)
            app.StatusTextArea.Value = sprintf("Sending send...");
            imagesend = app.ImageSend;
            write(app.ComDataWrite, imagesend, "uint8");
            app.StatusTextArea.Value = sprintf("Completed!");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectPortDropDownLabel = uilabel(app.UIFigure);
            app.SelectPortDropDownLabel.Text = 'Select Port';
            app.SelectPortDropDown = uidropdown(app.UIFigure);
            app.SelectPortDropDown.Items = {'Select COM', 'COM12', 'COM13'};
            app.SelectPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectPortDropDownOpening, true);
            app.SelectPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPortDropDownValueChanged, true);
            app.SelectPortDropDown.Value = 'Select COM';
            app.BaudrateDropDownLabel = uilabel(app.UIFigure);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.UIFigure);
            app.BaudrateDropDown.Items = {'Set baudrate', '9600', '14400', '19200', '57600', '76800', '115200'};
            app.BaudrateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudrateDropDownValueChanged, true);
            app.BaudrateDropDown.Value = 'Set baudrate';
            app.Image = uiimage(app.UIFigure);
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.PathTextAreaLabel = uilabel(app.UIFigure);
            app.PathTextAreaLabel.Text = 'Path';
            app.PathTextArea = uitextarea(app.UIFigure);
            app.PathTextArea.Value = {'Import your image...'};
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SendDataButton = uibutton(app.UIFigure, 'push');
            app.SendDataButton.ButtonPushedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.SendDataButton.Text = 'Send Data';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.ConnectPortButton = uibutton(app.UIFigure, 'push');
            app.ConnectPortButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectPorButtonPushed, true);
            app.ConnectPortButton.Text = 'Connect Port';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.BaudrateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudrateEditFieldLabel.Text = 'Baudrate';
            app.BaudrateEditField = uieditfield(app.UIFigure, 'text');
            app.TerahertzCommunicationTestTransmiterLabel = uilabel(app.UIFigure);
            app.TerahertzCommunicationTestTransmiterLabel.Text = 'Terahertz Communication Test (Transmiter)';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '校徽（紫底去外背景色）.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TX_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end