classdef RX_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        TerahertzCommunicationTestReceiverLabel  matlab.ui.control.Label
        BaudrateEditFieldLabel   matlab.ui.control.Label
        BaudrateEditField        matlab.ui.control.EditField
        PortEditFieldLabel       matlab.ui.control.Label
        PortEditField            matlab.ui.control.EditField
        StatusTextAreaLabel      matlab.ui.control.Label
        StatusTextArea           matlab.ui.control.TextArea
        BaudrateDropDownLabel    matlab.ui.control.Label
        BaudrateDropDown         matlab.ui.control.DropDown
        SelectPortDropDownLabel  matlab.ui.control.Label
        SelectPortDropDown       matlab.ui.control.DropDown
        ConnectPortButton        matlab.ui.control.Button
        ReceiveDataButton        matlab.ui.control.Button
    end


    properties (Access = private)
        ComDataRead     % Occupied when serial port open
        DataRead        % Serial data read from port
        ImageSave       % Data save as image matrix
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: SelectPortDropDown
        function SelectPortDropDownOpening(app, event)
            app.SelectPortDropDown.Items = serialportlist;
        end

        % Value changed function: BaudrateDropDown
        function BaudrateDropDownValueChanged(app, event)
            value = app.BaudrateDropDown.Value;
            app.StatusTextArea.Value = sprintf("%s %s successful!", "Set baudrate", value);
        end

        % Button pushed function: ConnectPortButton
        function ConnectPortButtonPushed(app, event)
            port = app.SelectPortDropDown.Value;
            baudrate = str2double(app.BaudrateDropDown.Value);
            % If the port is occupied, close it, then open it in MATLAB
            scom = instrfind;
            if scom ~= 0
                fclose(scom);
                delete(scom);
            else
                scom = serialport(port,baudrate);
                app.ComDataRead = scom;
            end
            app.StatusTextArea.Value = sprintf("%s %s successful!", "Connect port", port);
            app.PortEditField.Value = sprintf("%s", port);
            app.BaudrateEditField.Value = sprintf("%d", baudrate);
        end

        % Value changed function: SelectPortDropDown
        function SelectPortDropDownValueChanged(app, event)
            value = app.SelectPortDropDown.Value;
            app.StatusTextArea.Value = sprintf("%s %s successful!", "Set port", value);
        end

        % Button pushed function: ReceiveDataButton
        function ReceiveDataButtonPushed(app, event)
            app.StatusTextArea.Value = sprintf("Waiting for inputs...");
            s = app.ComDataRead;
            T = 0;
            while T==0
                flush(s);
                T = read(s, 1, "uint8");
                if T==27
                    app.StatusTextArea.Value = sprintf("Receiving data...");
                    M = read(s, 1, "uint8");
                    N = read(s, 1, "uint8");
                    capacity = M.*N;
                    data = read(s, capacity, "uint8");
                    app.StatusTextArea.Value = sprintf("Completed!");
                    break;
                else
                    T = 0;
                end
            end
            % Resize as image matrix
            imageread = zeros(N, M);
            i = 1;
            for k1 = 1 : N
                for k2 = 1 : 1 : M
                    imageread(k1, k2) = data(i);
                    i = i + 1;
                end
            end
            % Display image
            imageshow = uint8(imageread);
            figure;
            imshow(imageshow);
            % Save image
            [name,path] = uiputfile('*.bmp', 'Save image');
            file = fullfile(path, name);
            imwrite(imageshow, file, 'bmp');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReceiveDataButton = uibutton(app.UIFigure, 'push');
            app.ReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiveDataButtonPushed, true);
            app.ReceiveDataButton.Text = 'Receive Data';
            app.ConnectPortButton = uibutton(app.UIFigure, 'push');
            app.ConnectPortButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectPortButtonPushed, true);
            app.ConnectPortButton.Tooltip = {'Select a valid path to storage image'};
            app.ConnectPortButton.Text = 'Connect Port';
            app.SelectPortDropDown = uidropdown(app.UIFigure);
            app.SelectPortDropDown.Items = {'Select COM', 'COM12', 'COM13'};
            app.SelectPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectPortDropDownOpening, true);
            app.SelectPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPortDropDownValueChanged, true);
            app.SelectPortDropDown.Value = 'Select COM';
            app.SelectPortDropDownLabel = uilabel(app.UIFigure);
            app.SelectPortDropDownLabel.Text = 'Select Port';
            app.BaudrateDropDown = uidropdown(app.UIFigure);
            app.BaudrateDropDown.Items = {'Set baudrate', '9600', '14400', '19200', '57600', '76800', '115200'};
            app.BaudrateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudrateDropDownValueChanged, true);
            app.BaudrateDropDown.Value = 'Set baudrate';
            app.BaudrateDropDownLabel = uilabel(app.UIFigure);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.BaudrateEditField = uieditfield(app.UIFigure, 'text');
            app.BaudrateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudrateEditFieldLabel.Text = 'Baudrate';
            app.TerahertzCommunicationTestReceiverLabel = uilabel(app.UIFigure);
            app.TerahertzCommunicationTestReceiverLabel.Text = 'Terahertz Communication Test (Receiver)';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '校徽（紫底去外背景色）.png';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RX_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end